classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        GridLayout5            matlab.ui.container.GridLayout
        GridLayout7            matlab.ui.container.GridLayout
        DFTlengthEditField     matlab.ui.control.NumericEditField
        DFTlengthLabel         matlab.ui.control.Label
        samplingrateEditField  matlab.ui.control.NumericEditField
        samplingrateLabel      matlab.ui.control.Label
        GridLayout6            matlab.ui.container.GridLayout
        ButtonGroup            matlab.ui.container.ButtonGroup
        KaiserEditField        matlab.ui.control.NumericEditField
        KaiserEditFieldLabel   matlab.ui.control.Label
        KaiserButton           matlab.ui.control.RadioButton
        TriangularButton       matlab.ui.control.RadioButton
        BlackmanButton         matlab.ui.control.RadioButton
        HammingButton          matlab.ui.control.RadioButton
        HannButton             matlab.ui.control.RadioButton
        RectangularButton      matlab.ui.control.RadioButton
        StartButton            matlab.ui.control.Button
        CenterPanel            matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        RightPanel             matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        Property % Description
        sample_rate=8000; %采样频率
        T=1/8000; %采样时间
        DFT_length=32; 
        NUM_DISP=1024; %信号长度
        t= (0:1024-1).*(1/8000); %时间向量
        h; %窗
        betaa=1; %凯撒窗beta
    end
    
    methods (Access = public)
        
        function [x,y] = generate(app)
            %x = (1:app.NUM_DISP)/app.sample_rate;
            y = 10.*sin(2*pi*64*app.t) + sin(2*pi*250*app.t) +20.*sin(2*pi*256*app.t)+ 3.*sin(2*pi*260*app.t)+10.*sin(2*pi*512*app.t);
            y = y/max(abs(y)); %归一化
            y=[y,zeros(1,0)];
            %通带截止频率5kHz，通带衰减2dB，阻带截止频率30kHz，阻带衰减30dB的巴特沃斯低通滤波器
            %wp = 2 * pi * app.sample_rate/2;
            %ws = 2 * pi * 10000;
            %Rp = 0.01;
            %As = 3;
            %[N,wc] = buttord(wp, ws, Rp, As, 's') ;%
            %[B,A] = butter(N, wc, 'low', 's');
            %y = filter(B,A,y);
            plot(app.UIAxes, app.t, y);
            y=y.*app.h';
            plot(app.UIAxes2, app.t, y);
            y=fft(y,app.DFT_length);
            %fk=((1:app.DFT_length)-app.DFT_length/2)/app.DFT_length*app.sample_rate;
            %stem(app.UIAxes3,fk,y);
            %n = 0:app.DFT_length-1;
            P2 = abs(y/app.DFT_length);
            P2 = P2/max(abs(P2));
            %P1 = P2(1:app.DFT_length/2+1);
            %P1(2:end-1) = 2*P1(2:end-1);
            f = app.sample_rate*(-app.DFT_length/2:(app.DFT_length/2)-1)/app.DFT_length;
            P3=P2(app.DFT_length/2:app.DFT_length);
            P4=P2(1:(app.DFT_length/2-1));
            P5=[P3,P4];
            %f = app.sample_rate*(n-app.DFT_length/2)/app.DFT_length;
            stem(app.UIAxes3,f,P5);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.T=1/app.sample_rate;
            app.t=(0:app.NUM_DISP-1)*app.T;
            app.ButtonGroupSelectionChanged();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton==app.RectangularButton
                app.h=rectwin(app.NUM_DISP);
            elseif selectedButton==app.HammingButton
                app.h=hamming(app.NUM_DISP);
            elseif selectedButton==app.HannButton
                app.h=hann(app.NUM_DISP);
            elseif selectedButton==app.BlackmanButton
                app.h=blackman(app.NUM_DISP);
            elseif selectedButton==app.TriangularButton
                app.h=triang(app.NUM_DISP);
            elseif selectedButton==app.KaiserButton
                app.h=kaiser(app.NUM_DISP,app.betaa);
            end


        end

        % Value changed function: samplingrateEditField
        function samplingrateEditFieldValueChanged(app, event)
            value = app.samplingrateEditField.Value;
            app.sample_rate = value;
            app.startupFcn();
            
        end

        % Value changed function: DFTlengthEditField
        function DFTlengthEditFieldValueChanged(app, event)
            value = app.DFTlengthEditField.Value;
            app.DFT_length = value;
            app.startupFcn();
        end

        % Callback function
        function ButtonGroupSizeChanged(app, event)
            position = app.ButtonGroup.Position;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.generate();
        end

        % Callback function
        function KaiserbetaValueChanged(app, event)
 
        end

        % Value changed function: KaiserEditField
        function KaiserEditFieldValueChanged(app, event)
            app.betaa = app.KaiserEditField.Value;
            app.startupFcn();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {279, '1x', 116};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.StartButton = uibutton(app.GridLayout6, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ButtonGroup = uibuttongroup(app.GridLayout6);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.RectangularButton = uiradiobutton(app.ButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.RectangularButton.Value = true;
            app.HannButton = uiradiobutton(app.ButtonGroup);
            app.HannButton.Text = 'Hann';
            app.HammingButton = uiradiobutton(app.ButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.BlackmanButton = uiradiobutton(app.ButtonGroup);
            app.BlackmanButton.Text = 'Blackman';
            app.TriangularButton = uiradiobutton(app.ButtonGroup);
            app.TriangularButton.Text = 'Triangular';
            app.KaiserButton = uiradiobutton(app.ButtonGroup);
            app.KaiserButton.Text = 'Kaiser';
            app.KaiserEditFieldLabel = uilabel(app.ButtonGroup);
            app.KaiserEditFieldLabel.Text = 'Kaiser β';
            app.KaiserEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.KaiserEditField.ValueChangedFcn = createCallbackFcn(app, @KaiserEditFieldValueChanged, true);
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.samplingrateLabel = uilabel(app.GridLayout7);
            app.samplingrateLabel.Text = {'sampling '; 'rate'};
            app.samplingrateEditField = uieditfield(app.GridLayout7, 'numeric');
            app.samplingrateEditField.ValueChangedFcn = createCallbackFcn(app, @samplingrateEditFieldValueChanged, true);
            app.DFTlengthLabel = uilabel(app.GridLayout7);
            app.DFTlengthLabel.Text = {'DFT '; 'length'};
            app.DFTlengthEditField = uieditfield(app.GridLayout7, 'numeric');
            app.DFTlengthEditField.ValueChangedFcn = createCallbackFcn(app, @DFTlengthEditFieldValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end